classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        ImageAxes        matlab.ui.control.UIAxes
        LayersLabel      matlab.ui.control.Label
        DropDown         matlab.ui.control.DropDown
        ZSliderLabel     matlab.ui.control.Label
        ZSlider          matlab.ui.control.Slider
        TimeSliderLabel  matlab.ui.control.Label
        TimeSlider       matlab.ui.control.Slider
    end

    
    properties (Access = public )
        reader
        image_bits
        channel
        Image
        Filtered % filtered image
        BW% current binarized image
        stats
        curr_z
        curr_t
        obj % track_analyzer object
        %update_img=@update_img
        %update_stk=@update_stack
        toolboxApp
        view_layer
        img_dim
    end
    
    methods (Access = public)
        
        function update_image(app)
            
            % Create histograms based on number of color channels
            switch length(size(app.Image))
                case 1
                    % Display the grayscale image
                    imagesc(app.ImageAxes,app.Image);
                    
                case 3
                    %Estimate range. 
                    img_range = double([min(app.(app.view_layer)(:)),max(app.(app.view_layer)(:))]);
                    % Display the truecolor image
                    imshow(app.(app.view_layer)(:,:,app.curr_z),img_range,'Parent',app.ImageAxes)
            end

        end
        
        function update_stack(app)
            app.Image = get_stack(app.reader,app.curr_t,app.channel);
            update_image(app)
            %p=1
        end
       
        function return_str = channel_str(app)
        
            return_str = ['channel_',pad(num2str(app.channel),2,'left','0')];
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, obj)

            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            %axis(app.ImageAxes, 'image');
            
            % Update the image and histograms
            img_file_name = obj.exp_info.img_file;
            
            % initialize time and channels. 
            app.curr_t=1;
            app.channel=1;
            app.curr_z=1;
            app.obj=obj;
            % Add image reader to app
            app.reader = bfGetReader(img_file_name);
            app.image_bits=app.reader.getBitsPerPixel;   
            % Update components
            app.ZSlider.Limits=[1,obj.exp_info.z_planes];
            frames = length(obj.exp_info.time_series);
            if frames == 1
                app.TimeSlider.Limits=[1,2];
            else
                app.TimeSlider.Limits=[1,length(obj.exp_info.time_series)];
            end
            app.view_layer='Image';
            %Initialize image
            update_stack(app);
            app.img_dim = length(size(app.Image));

            % Call diaglog app
            app.toolboxApp=tool_box(app);
            
        end

        % Window scroll wheel function: UIFigure
        function UIFigureWindowScrollWheel(app, event)
            verticalScrollCount = event.VerticalScrollCount;
            app.curr_z = app.curr_z - verticalScrollCount;
            if (app.curr_z < 1)
                app.curr_z = 1;
            elseif (app.curr_z > app.obj.exp_info.z_planes)
                app.curr_z = app.obj.exp_info.z_planes;
            end
            
            %Update image
            app.ImageAxes.Children.CData=squeeze(app.(app.view_layer)(:,:,app.curr_z));
           
            %Update slider. 
            app.ZSlider.Value=app.curr_z;
        end

        % Value changed function: TimeSlider
        function t_change(app, event)
            app.curr_t = app.TimeSlider.Value;
            update_stack(app)
        end

        % Value changed function: DropDown
        function layer_change(app, event)
            value = app.DropDown.Value;
            
            % Figure out which layer to display. 
            switch value
                
                case 'Image'
                    
                    app.view_layer='Image';
                    
                case 'Filtered'
                    % Check if filtered exists yet. 
                    if isempty(app.Filtered)
                        app.view_layer='Image';
                    else
                        app.view_layer='Filtered';
                    end
                    
                case 'BW'      
                    % Check if BW exists yet. 
                    if isempty(app.BW)
                        app.view_layer='Image';
                    else
                        app.view_layer='BW';
                    end              
            end
            % Set current layer. 
            app.update_image();
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.UIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @UIFigureWindowScrollWheel, true);
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.LayersLabel = uilabel(app.UIFigure);
            app.LayersLabel.Text = 'Layers';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'Image', 'Filtered', 'BW'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @layer_change, true);
            app.DropDown.Value = 'Image';
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.MajorTicks = [0 20 40 60 80 100];
            app.TimeSlider.MajorTickLabels = {'0', '20', '40', '60', '80', '100'};
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @t_change, true);
            app.TimeSlider.MinorTicks = [0 4 8 12 16 20 24 28 32 36 40 44 48 52 56 60 64 68 72 76 80 84 88 92 96 100];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end