classdef viewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        ThresholdEditField       matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel  matlab.ui.control.Label
        CheckBox                 matlab.ui.control.CheckBox
        ZstepSlider              matlab.ui.control.Slider
        ZstepSliderLabel         matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        img % Description
    end
    
    properties (Access = private)
        z_slices % Description
        initial_thresh % Description
        increment % Description
    end
    
    methods (Access = private)
        
        function [Rmn Rmx] = WL2R(app,W,L)
            Rmn = L - (W/2);
            Rmx = L + (W/2);
            if (Rmn >= Rmx)
                Rmx = Rmn + 1;
            end
        end
        
        function mouseScroll(app, ~, eventdata)
            UPDN = eventdata.VerticalScrollCount;
            Z = app.ZstepSlider.Value;
            Z = Z - UPDN;
            if (Z < 1)
                Z = 1;
            elseif (Z > app.ZstepSlider.Limits(2))
                Z = app.ZstepSlider.Limits(2);
            end
            app.ZstepSlider.Value=Z;
            app.update_image;
        end
    end
    
    methods (Access = public)
        
        function results = update_image(app)
            
            % Display image. 
            MinV = 0;
            MaxV = max(app.img(:));
            LevV = (double( MaxV) + double(MinV)) / 2;
            Win = double(MaxV) - double(MinV);
            WLAdjCoe = (Win + 1)/1024;
            FineTuneC = [1 1/16];    % Regular/Fine-tune mode coefficients
            [Rmin Rmax] = app.WL2R(Win, LevV);
            imshow(app.img(:,:,round(app.ZstepSlider.Value)),[Rmin Rmax],'Parent',app.UIAxes);
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, img, varargin)

            % First get some info from the track_obj. 
            app.z_slices = size(img,3);  % number of slices
            app.ZstepSlider.Limits = [1,app.z_slices];
            app.ZstepSlider.Value = round(size(img,3)/2);
            
            % add image to app;
            app.img=img;
            vals = img(:);
            app.increment = 5* (max(vals)-min(vals))/100;

            % Update image. 
            app.update_image;

            %Setup some stuff. 
            app.UIFigure.WindowScrollWheelFcn=@app.mouseScroll;
            
            p = inputParser;
            p.addParameter('zval',@isinteger);
            p.addParameter('initial_thresh',prctile(vals,90),@isinteger);
            p.addParameter('filter_on',false,@islogical);
            p.parse(varargin{:})

            if isfield(p.Results,"zval")
                S = p.Results.zval;
            end
            app.initial_thresh = p.Results.initial_thresh;

            
        end

        % Value changed function: ZstepSlider
        function ZstepSliderValueChanged(app, event)
            value = app.ZstepSlider.Value;
            app.update_image;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.ZstepSliderLabel = uilabel(app.UIFigure);
            app.ZstepSliderLabel.Text = 'Z-step';
            app.ZstepSlider = uislider(app.UIFigure);
            app.ZstepSlider.ValueChangedFcn = createCallbackFcn(app, @ZstepSliderValueChanged, true);
            app.CheckBox = uicheckbox(app.UIFigure);
            app.CheckBox.Text = '';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = viewer(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end